classdef Reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ReconstructImageButton     matlab.ui.control.Button
        SaveImageButton            matlab.ui.control.Button
        SelectReconstructionMethodListBox  matlab.ui.control.ListBox
        ReconstructionMethodLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        CurrentFileTextArea        matlab.ui.control.TextArea
        CurrentFileTextAreaLabel   matlab.ui.control.Label
        ReconstructedImagePanel    matlab.ui.container.Panel
        OpenFileButton             matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        CSCameraReconstructionSoftwareLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        VerboseMode logical = true; % Set to false to silence console prints
    end
    
    methods (Access = private)
        % Only print to the console if VerboseMode is turned on
        function logMsg(app, inputData)
            if app.VerboseMode
                % Check if the input is text (character vector or string)
                if ischar(inputData) || isstring(inputData)
                    disp(['[DEBUG] ', char(inputData)]);
                else
                    % If it is a table or matrix, print it on its own line
                    disp('[DEBUG] Data output:');
                    disp(inputData);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set window size to 1024 pixels wide by 768 pixels tall
            % The first two numbers (100, 100) dictate where it spawns on your monitor
            app.UIFigure.Position = [100, 100, 1024, 768];
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            
            % Open the file dialog
            [file, path] = uigetfile({'*.csv', 'CSV Files'; ...
                                      '*.*', 'All Files'}, 'Select a CSV File');
            
            % Restore window to top of view
            figure(app.UIFigure);

            % Check if the user cancels/closes file select window.
            if isequal(file, 0)
                return;
            end;

            fullFileName = fullfile(path, file);

            % Update textbox with filename
            app.CurrentFileTextArea.Value = fullFileName;

            try
                % Use readtable or readmatrix depending on data structure
                myData = readtable(fullFileName);

                % Use 'myData' for reconstruction logic
                
                % 1. Log the text message
                app.logMsg('Successfully loaded file. Previewing data:'); 
                
                % 2. Log the actual data table
                app.logMsg(head(myData));
                
            catch ME

                uialert(app.UIFigure, ['Error reading CSV: ', ME.message], 'Import Error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CSCameraReconstructionSoftwareLabel = uilabel(app.GridLayout);
            app.CSCameraReconstructionSoftwareLabel.Text = 'CSCamera Reconstruction Software';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'eng_logo.png');
            app.OpenFileButton = uibutton(app.GridLayout, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ReconstructedImagePanel = uipanel(app.GridLayout);
            app.ReconstructedImagePanel.Title = 'Reconstructed Image:';
            app.CurrentFileTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentFileTextAreaLabel.Text = 'Current File:';
            app.CurrentFileTextArea = uitextarea(app.GridLayout);
            app.CurrentFileTextArea.Editable = 'off';
            app.Image2 = uiimage(app.GridLayout);
            app.ReconstructionMethodLabel = uilabel(app.GridLayout);
            app.ReconstructionMethodLabel.Text = 'Select Reconstruction Method:';
            app.SelectReconstructionMethodListBox = uilistbox(app.GridLayout);
            app.SelectReconstructionMethodListBox.Items = {'Fourier Transform', 'Cosine Transform', 'Total Variance', 'CNN'};
            app.SelectReconstructionMethodListBox.Value = 'Fourier Transform';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.ReconstructImageButton = uibutton(app.GridLayout, 'push');
            app.ReconstructImageButton.Text = 'Reconstruct Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end